classdef TTL_2_CORTE2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PLAYButton                      matlab.ui.control.StateButton
        CargarSealButton                matlab.ui.control.Button
        JuanEstebanSarriaMosqueraLabel  matlab.ui.control.Label
        DubanSmithCastroPotosiLabel     matlab.ui.control.Label
        SimuladordeProcesamientodeSealesdeAudioLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarSealButton
        function CargarSealButtonPushed(app, event)
            
            % cuado cargo una nueva señal de audio, se limpian todas las graficas
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            cla(app.UIAxes3,"reset");
            cla(app.UIAxes4,"reset");
            
            clear sound;
            global signal Fs;

            %% carga la señal de audio
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo de audio');
            fullPath = fullfile(path, file);
            [signal, Fs] = audioread(fullPath);

            %% filtro pasabajo con frecuencia de corte de 4 kHz
            audioFiltrado = lowpass(signal, 4000, Fs);
     
            %% Muestrea la señal a 8000 Hz utilizando la función resample
            audioMuestreado = resample(audioFiltrado, 8000, Fs);

            %% Cuantificación
            n = 8; % Número de bits para la cuantificación
            L = 2^n; % Número de niveles de cuantificación
            delta = (max(audioMuestreado) - min(audioMuestreado)) / L; % Intervalo de cuantificación
 
            % La funcion "floor" redondea cada muestra al nivel más cercano por debajo. Luego multiplica por 
            % el intervalo de cuantización y suma el valor mínimo de la señal para obtener la señal cuantificada.
            audioCuantificado = floor((audioMuestreado - min(audioMuestreado)) / delta) * delta + min(audioMuestreado);
            
            t_Cuantificado = (0:length(audioCuantificado)-1)/8000; % Vector de tiempo
            plot(app.UIAxes,t_Cuantificado,audioCuantificado);
            
            app.UIAxes.Title.String = "Señal Cuantizada n=8 bits";
            app.UIAxes.YLim = [-1.1 1.1];
            app.UIAxes.XLim = [0 max(t_Cuantificado)+1];
            app.UIAxes.XGrid = "on";
            app.UIAxes.YGrid = "on";
            app.UIAxes.XLabel.String = "Tiempo (s)";
            app.UIAxes.YLabel.String = "Amplitud";
        end

        % Value changed function: PLAYButton
        function PLAYButtonValueChanged(app, event)
            clear sound;
            global signal Fs;

            value = app.PLAYButton.Value
            if value == true
                % Reproduce archivo de audio Original
                sound(signal,Fs);
                app.PLAYButton.Text = "Pause";
            else
                % Pausar archivo de audio
                clear sound;
                app.PLAYButton.Text = "Play";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SimuladordeProcesamientodeSealesdeAudioLabel = uilabel(app.UIFigure);
            app.SimuladordeProcesamientodeSealesdeAudioLabel.Text = 'Simulador de Procesamiento de Señales de Audio';
            app.DubanSmithCastroPotosiLabel = uilabel(app.UIFigure);
            app.DubanSmithCastroPotosiLabel.Text = 'Duban Smith Castro Potosi';
            app.JuanEstebanSarriaMosqueraLabel = uilabel(app.UIFigure);
            app.JuanEstebanSarriaMosqueraLabel.Text = 'Juan Esteban Sarria Mosquera';
            app.CargarSealButton = uibutton(app.UIFigure, 'push');
            app.CargarSealButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSealButtonPushed, true);
            app.CargarSealButton.Text = 'Cargar Señal';
            app.PLAYButton = uibutton(app.UIFigure, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonValueChanged, true);
            app.PLAYButton.Text = 'PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTL_2_CORTE2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end